classdef Metodos_Proyecto_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PanelLagrange               matlab.ui.container.Panel
        PanelTaylor                 matlab.ui.container.Panel
        FuncionLabel                matlab.ui.control.Label
        hEditField                  matlab.ui.control.NumericEditField
        hEditFieldLabel             matlab.ui.control.Label
        nEditField                  matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        NumGraficasEditField_Tay    matlab.ui.control.NumericEditField
        FuncionEditField_Tay        matlab.ui.control.EditField
        GraficasEditFieldLabel_7    matlab.ui.control.Label
        ResultadosLabel_Tay         matlab.ui.control.Label
        DatosLabel_Tay              matlab.ui.control.Label
        UITable_Tay                 matlab.ui.control.Table
        PolinomioLabel_Tay          matlab.ui.control.Label
        IteracionesLabel_Tay        matlab.ui.control.Label
        ErrorLabel_Tay              matlab.ui.control.Label
        RaizLabel_Tay               matlab.ui.control.Label
        GraficarButton_Tay          matlab.ui.control.Button
        LimpiarButton_Tay           matlab.ui.control.Button
        CalcularButton_Tay          matlab.ui.control.Button
        PanelNewton                 matlab.ui.container.Panel
        FuncionLabel_2              matlab.ui.control.Label
        XiEditField                 matlab.ui.control.NumericEditField
        ToleranciaEditField         matlab.ui.control.NumericEditField
        InteracionesEditField       matlab.ui.control.NumericEditField
        ResultadosLabel_Newt        matlab.ui.control.Label
        DatosLabel_Newt             matlab.ui.control.Label
        UITable_Newt                matlab.ui.control.Table
        PolinomioLabel_Newt         matlab.ui.control.Label
        IteracionesLabel_Newt       matlab.ui.control.Label
        ErrorLabel_Newt             matlab.ui.control.Label
        RaizLabel_Newt              matlab.ui.control.Label
        GraficarButton_Newt         matlab.ui.control.Button
        LimpiarButton_Newt          matlab.ui.control.Button
        CalcularButton_Newt         matlab.ui.control.Button
        NumGraficasEditField_Newt   matlab.ui.control.NumericEditField
        GraficasEditFieldLabel_6    matlab.ui.control.Label
        InteracionesEditFieldLabel  matlab.ui.control.Label
        ToleranciaEditFieldLabel    matlab.ui.control.Label
        FuncionEditField_Newt       matlab.ui.control.EditField
        XiEditFieldLabel            matlab.ui.control.Label
        PanelMinimos                matlab.ui.container.Panel
        PanelMenu                   matlab.ui.container.Panel
        ButtonMinimos               matlab.ui.control.Button
        ButtonLagrange              matlab.ui.control.Button
        ButtonTaylor                matlab.ui.control.Button
        ButtonNewton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        xi_hist
        fx_hist
        dfx_hist
        iter_convergencia
        convergio
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PanelTaylor.Visible = 'off';
            app.PanelNewton.Visible = 'on';
            app.PanelMinimos.Visible = 'off';
            app.PanelLagrange.Visible = 'off';
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: ButtonNewton
        function ButtonNewtonPushed(app, event)
            app.PanelTaylor.Visible = 'on';
            app.PanelNewton.Visible = 'off';
            app.PanelMinimos.Visible = 'off';
            app.PanelLagrange.Visible = 'off';
        end

        % Button pushed function: ButtonTaylor
        function ButtonTaylorPushed(app, event)
            app.PanelTaylor.Visible = 'off';
            app.PanelNewton.Visible = 'on';
            app.PanelMinimos.Visible = 'off';
            app.PanelLagrange.Visible = 'off';
        end

        % Button pushed function: ButtonLagrange
        function ButtonLagrangePushed(app, event)
            app.PanelTaylor.Visible = 'off';
            app.PanelNewton.Visible = 'off';
            app.PanelMinimos.Visible = 'off';
            app.PanelLagrange.Visible = 'on';
        end

        % Button pushed function: ButtonMinimos
        function ButtonMinimosPushed(app, event)
            app.PanelTaylor.Visible = 'off';
            app.PanelNewton.Visible = 'off';
            app.PanelMinimos.Visible = 'on';
            app.PanelLagrange.Visible = 'off';
        end

        % Button pushed function: CalcularButton_Newt
        function CalcularButton_NewtPushed(app, event)
            try
                f_str = app.FuncionEditField_Newt.Value;
                if isempty(f_str)
                    uialert(app.UIFigure,'Ingrese una función para Newton','Error');
                    return;
                end
                if app.InteracionesEditField.Value <= 0
                    uialert(app.UIFigure,'Las iteraciones deben ser mayores a 0','Error');
                    return;
                end
            
                if app.ToleranciaEditField.Value <= 0
                    uialert(app.UIFigure,'La tolerancia debe ser mayor a 0','Error');
                    return;
                end
                xi = app.XiEditField.Value;
                es = app.ToleranciaEditField.Value;
                maxIter = app.InteracionesEditField.Value;
                convergio = false;
                iter_convergencia = maxIter;
    
                x = sym('x');
                fs = str2sym(f_str);
                dfs = diff(fs,x);
    
                tabla = [];
                xi_hist = [];
                fx_hist = [];
                dfx_hist = [];
    
                for i = 1:maxIter
    
                    fxi = double(subs(fs,x,xi));
                    dfxi = double(subs(dfs,x,xi));
                
                    if dfxi == 0
                        error('La derivada es cero, no se puede continuar');
                    end
                
                    % Guardar datos
                    xi_hist = [xi_hist; xi];
                    fx_hist = [fx_hist; fxi];
                    dfx_hist = [dfx_hist; dfxi];
                
                    xi_new = xi - fxi/dfxi;
                
                    if xi_new ~= 0
                        ea = abs((xi_new - xi)/xi_new)*100;
                    else
                        ea = 0;
                    end
                
                    tabla = [tabla; i xi fxi ea];
                    if ~convergio && ea < es
                        convergio = true;
                        iter_convergencia = i;
                    end
                                            
                    xi = xi_new;
                end
                app.xi_hist = xi_hist;
                app.fx_hist = fx_hist;
                app.dfx_hist = dfx_hist;
                app.iter_convergencia = iter_convergencia;
                app.convergio = convergio;
    
                app.UITable_Newt.Data = tabla;
                app.UITable_Newt.ColumnName = {'Iter','xi','f(xi)','Error %'};
    
                app.RaizLabel_Newt.Text = ['Raíz: ', num2str(xi_new)];
                app.ErrorLabel_Newt.Text = ['Error: ', num2str(ea), ' %'];
                app.IteracionesLabel_Newt.Text = ['Iteraciones: ', num2str(i)];
                app.PolinomioLabel_Newt.Text = '';
                if app.convergio
                    app.IteracionesLabel_Newt.Text = ['Convergió en ', num2str(app.iter_convergencia), ' iteraciones'];
                else
                    app.IteracionesLabel_Newt.Text = 'No convergió';
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: CalcularButton_Tay
        function CalcularButton_TayPushed(app, event)
            try
                f_str = app.FuncionEditField_Tay.Value;
                if isempty(f_str)
                    uialert(app.UIFigure,'Ingrese una función para Taylor','Error');
                    return;
                end
            
                if app.nEditField.Value <= 0
                    uialert(app.UIFigure,'El orden debe ser mayor a 0','Error');
                    return;
                end
                a = app.aEditField.Value;
                n = app.nEditField.Value;
                h = app.hEditField.Value;
            
                x = sym('x');
                fs = str2sym(f_str);
            
                xi = a + h;
            
                tabla = [];
            
                valorReal = double(subs(fs, x, xi));
            
                for k = 1:n
                    
                    % Polinomio de orden k
                    T = taylor(fs, x, 'ExpansionPoint', a, 'Order', k+1);
            
                    valorAprox = double(subs(T, x, xi));
            
                    if valorReal ~= 0
                        err = abs((valorReal - valorAprox)/valorReal)*100;
                    else
                        err = 0;
                    end
            
                    tabla = [tabla; k valorReal valorAprox err];
                end
            
                % Guardar en tabla
                app.UITable_Tay.Data = tabla;
                app.UITable_Tay.ColumnName = {'Orden','Valor Real','Aprox','Error %'};
            
                % Mostrar resultado final (orden n)
                app.RaizLabel_Tay.Text = '';
                app.ErrorLabel_Tay.Text = ['Error final: ', num2str(err), ' %'];
                app.IteracionesLabel_Tay.Text = ['Orden usado: ', num2str(n)];
                app.PolinomioLabel_Tay.Text = ['Polinomio grado ', num2str(n)];
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: GraficarButton_Newt
        function GraficarButton_NewtPushed(app, event)
            try
                if isempty(app.xi_hist)
                    uialert(app.UIFigure,'Primero debes calcular','Error');
                    return;
                end
    
                xi_hist = app.xi_hist;
                fx_hist = app.fx_hist;
                dfx_hist = app.dfx_hist;
    
                if app.convergio
                    limite = min(app.iter_convergencia + 1, length(xi_hist));
                else
                    limite = length(xi_hist);
                end
    
                numGraficas = app.NumGraficasEditField_Newt.Value;
    
                if numGraficas <= 0
                    error('Cantidad inválida');
                end
    
                maxGraficas = min(numGraficas, limite);
                inicio = max(1, limite - maxGraficas + 1);
    
                filas = ceil(sqrt(maxGraficas));
                columnas = ceil(maxGraficas / filas);
    
                figure('Name','Newton-Raphson','NumberTitle','off')
    
                indices = inicio:limite;
    
                if length(indices) > maxGraficas
                    indices = indices(end-maxGraficas+1:end);
                end
    
                for idx = 1:length(indices)
    
                    k = indices(idx);
    
                    subplot(filas, columnas, idx)
    
                    xi_k = xi_hist(k);
                    fxi_k = fx_hist(k);
                    dfxi_k = dfx_hist(k);
    
                    f_str = app.FuncionEditField_Newt.Value;
                    x = sym('x');
                    fs = str2sym(f_str);
    
                    fplot(fs,[xi_k-2 xi_k+2],'m')
                    hold on
                    yline(0,'k--')
    
                    plot(xi_k, fxi_k,'ro')
    
                    xt = linspace(xi_k-2, xi_k+2,100);
                    yt = fxi_k + dfxi_k*(xt - xi_k);
                    plot(xt, yt,'b--')
    
                    xi_next = xi_k - fxi_k/dfxi_k;
                    plot(xi_next,0,'go')
    
                    % marcar convergencia
                    if app.convergio && k == app.iter_convergencia
                        title(['Iter ', num2str(k), ' (Convergencia)'])
                    else
                        title(['Iteración ', num2str(k)])
                    end
    
                    grid on
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: GraficarButton_Tay
        function GraficarButton_TayPushed(app, event)
            try
                f_str = app.FuncionEditField_Tay.Value;
                a = app.aEditField.Value;
                n = app.nEditField.Value;
            
                if isempty(f_str)
                    error('Ingrese una función válida');
                end
            
                x = sym('x');
                fs = str2sym(f_str);
            
                % Cantidad de gráficas (igual que Newton)
                numGraficas = app.NumGraficasEditField_Tay.Value;
            
                if numGraficas <= 0
                    error('Cantidad inválida');
                end
            
                % No puedes tener más órdenes que n
                maxGraficas = min(numGraficas, n);
            
                filas = ceil(sqrt(maxGraficas));
                columnas = ceil(maxGraficas / filas);
            
                figure('Name','Serie de Taylor','NumberTitle','off')
            
                % Rango de gráfica
                rango = [a-2, a+2];
            
                % Graficar diferentes órdenes
                for k = 1:maxGraficas
            
                    subplot(filas, columnas, k)
            
                    % Polinomio de orden k
                    T = taylor(fs, x, 'ExpansionPoint', a, 'Order', k+1);
            
                    fplot(fs, rango,'b','LineWidth',1.2)
                    hold on
                    fplot(T, rango,'r--','LineWidth',1.2)
            
                    title(['Orden ', num2str(k)])
                    legend('Real','Taylor')
                    grid on
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: LimpiarButton_Tay
        function LimpiarButton_TayPushed(app, event)
            app.FuncionEditField_Tay.Value = "";

            app.aEditField.Value = 0;
            app.nEditField.Value = 0;
            app.hEditField.Value = 0;
            app.NumGraficasEditField_Tay.Value = 0;

            app.UITable_Tay.Data = [];

            app.RaizLabel_Tay.Text = 'Raiz';
            app.ErrorLabel_Tay.Text = 'Error';
            app.IteracionesLabel_Tay.Text = 'Iteraciones';
            app.PolinomioLabel_Tay.Text = 'Polinomio';
        end

        % Button pushed function: LimpiarButton_Newt
        function LimpiarButton_NewtPushed(app, event)
            app.FuncionEditField_Newt.Value = "";
            
            app.XiEditField.Value = 0;
            app.ToleranciaEditField.Value = 0;
            app.InteracionesEditField.Value = 0;
            app.NumGraficasEditField_Newt.Value = 0;

            app.UITable_Newt.Data = [];

            app.RaizLabel_Newt.Text = 'Raiz';
            app.ErrorLabel_Newt.Text = 'Error';
            app.IteracionesLabel_Newt.Text = 'Iteraciones';
            app.PolinomioLabel_Newt.Text = 'Polinomio';

            app.xi_hist = [];
            app.fx_hist = [];
            app.dfx_hist = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelMenu = uipanel(app.UIFigure);
            app.PanelMenu.Title = 'Menu';
            app.ButtonNewton = uibutton(app.PanelMenu, 'push');
            app.ButtonNewton.ButtonPushedFcn = createCallbackFcn(app, @ButtonNewtonPushed, true);
            app.ButtonNewton.Text = 'Serie de Taylor';
            app.ButtonTaylor = uibutton(app.PanelMenu, 'push');
            app.ButtonTaylor.ButtonPushedFcn = createCallbackFcn(app, @ButtonTaylorPushed, true);
            app.ButtonTaylor.Text = 'Newton-Raphson';
            app.ButtonLagrange = uibutton(app.PanelMenu, 'push');
            app.ButtonLagrange.ButtonPushedFcn = createCallbackFcn(app, @ButtonLagrangePushed, true);
            app.ButtonLagrange.Text = 'Interpolación de Lagrange';
            app.ButtonMinimos = uibutton(app.PanelMenu, 'push');
            app.ButtonMinimos.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinimosPushed, true);
            app.ButtonMinimos.Text = 'Minimos Cuadrados';
            app.PanelMinimos = uipanel(app.UIFigure);
            app.PanelMinimos.Title = 'Minimos Cuadrados';
            app.PanelNewton = uipanel(app.UIFigure);
            app.PanelNewton.Title = 'Newton-Raphson';
            app.XiEditFieldLabel = uilabel(app.PanelNewton);
            app.XiEditFieldLabel.Text = 'Xi';
            app.FuncionEditField_Newt = uieditfield(app.PanelNewton, 'text');
            app.ToleranciaEditFieldLabel = uilabel(app.PanelNewton);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.InteracionesEditFieldLabel = uilabel(app.PanelNewton);
            app.InteracionesEditFieldLabel.Text = 'Interaciones';
            app.GraficasEditFieldLabel_6 = uilabel(app.PanelNewton);
            app.GraficasEditFieldLabel_6.Text = 'Graficas';
            app.NumGraficasEditField_Newt = uieditfield(app.PanelNewton, 'numeric');
            app.CalcularButton_Newt = uibutton(app.PanelNewton, 'push');
            app.CalcularButton_Newt.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_NewtPushed, true);
            app.CalcularButton_Newt.Text = 'Calcular';
            app.LimpiarButton_Newt = uibutton(app.PanelNewton, 'push');
            app.LimpiarButton_Newt.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButton_NewtPushed, true);
            app.LimpiarButton_Newt.Text = 'Limpiar';
            app.GraficarButton_Newt = uibutton(app.PanelNewton, 'push');
            app.GraficarButton_Newt.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_NewtPushed, true);
            app.GraficarButton_Newt.Text = 'Graficar';
            app.RaizLabel_Newt = uilabel(app.PanelNewton);
            app.RaizLabel_Newt.Text = 'Raiz';
            app.ErrorLabel_Newt = uilabel(app.PanelNewton);
            app.ErrorLabel_Newt.Text = 'Error';
            app.IteracionesLabel_Newt = uilabel(app.PanelNewton);
            app.IteracionesLabel_Newt.Text = 'Iteraciones';
            app.PolinomioLabel_Newt = uilabel(app.PanelNewton);
            app.PolinomioLabel_Newt.Text = 'Polinomio';
            app.UITable_Newt = uitable(app.PanelNewton);
            app.UITable_Newt.ColumnName = {'Iter'; 'xi'; 'f(xi)'; 'Error %'};
            app.UITable_Newt.RowName = {};
            app.DatosLabel_Newt = uilabel(app.PanelNewton);
            app.DatosLabel_Newt.Text = 'Datos';
            app.ResultadosLabel_Newt = uilabel(app.PanelNewton);
            app.ResultadosLabel_Newt.Text = 'Resultados';
            app.InteracionesEditField = uieditfield(app.PanelNewton, 'numeric');
            app.ToleranciaEditField = uieditfield(app.PanelNewton, 'numeric');
            app.XiEditField = uieditfield(app.PanelNewton, 'numeric');
            app.FuncionLabel_2 = uilabel(app.PanelNewton);
            app.FuncionLabel_2.Text = 'Funcion';
            app.PanelTaylor = uipanel(app.UIFigure);
            app.PanelTaylor.Title = 'Serie de Taylor';
            app.CalcularButton_Tay = uibutton(app.PanelTaylor, 'push');
            app.CalcularButton_Tay.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_TayPushed, true);
            app.CalcularButton_Tay.Text = 'Calcular';
            app.LimpiarButton_Tay = uibutton(app.PanelTaylor, 'push');
            app.LimpiarButton_Tay.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButton_TayPushed, true);
            app.LimpiarButton_Tay.Text = 'Limpiar';
            app.GraficarButton_Tay = uibutton(app.PanelTaylor, 'push');
            app.GraficarButton_Tay.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_TayPushed, true);
            app.GraficarButton_Tay.Text = 'Graficar';
            app.RaizLabel_Tay = uilabel(app.PanelTaylor);
            app.RaizLabel_Tay.Text = 'Raiz';
            app.ErrorLabel_Tay = uilabel(app.PanelTaylor);
            app.ErrorLabel_Tay.Text = 'Error';
            app.IteracionesLabel_Tay = uilabel(app.PanelTaylor);
            app.IteracionesLabel_Tay.Text = 'Iteraciones';
            app.PolinomioLabel_Tay = uilabel(app.PanelTaylor);
            app.PolinomioLabel_Tay.Text = 'Polinomio';
            app.UITable_Tay = uitable(app.PanelTaylor);
            app.UITable_Tay.ColumnName = {'Orden'; 'Valor Real'; 'Aprox'; 'Error %'};
            app.UITable_Tay.RowName = {};
            app.DatosLabel_Tay = uilabel(app.PanelTaylor);
            app.DatosLabel_Tay.Text = 'Datos';
            app.ResultadosLabel_Tay = uilabel(app.PanelTaylor);
            app.ResultadosLabel_Tay.Text = 'Resultados';
            app.GraficasEditFieldLabel_7 = uilabel(app.PanelTaylor);
            app.GraficasEditFieldLabel_7.Text = 'Graficas';
            app.FuncionEditField_Tay = uieditfield(app.PanelTaylor, 'text');
            app.NumGraficasEditField_Tay = uieditfield(app.PanelTaylor, 'numeric');
            app.aEditFieldLabel = uilabel(app.PanelTaylor);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.PanelTaylor, 'numeric');
            app.aEditField.Limits = [0 Inf];
            app.nEditFieldLabel = uilabel(app.PanelTaylor);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.PanelTaylor, 'numeric');
            app.nEditField.Limits = [0 Inf];
            app.hEditFieldLabel = uilabel(app.PanelTaylor);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.PanelTaylor, 'numeric');
            app.hEditField.Limits = [0 Inf];
            app.FuncionLabel = uilabel(app.PanelTaylor);
            app.FuncionLabel.Text = 'Funcion';
            app.PanelLagrange = uipanel(app.UIFigure);
            app.PanelLagrange.Title = 'Interpolación de Lagrange';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Metodos_Proyecto_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end